classdef forcePlateAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CompiletimepointsCheckBox     matlab.ui.control.CheckBox
        ExperimentDropDown            matlab.ui.control.DropDown
        ExperimentDropDownLabel       matlab.ui.control.Label
        PathEditField                 matlab.ui.control.EditField
        SetdirectoryButton            matlab.ui.control.Button
        ExtractpeakrangeHzDropDown    matlab.ui.control.DropDown
        ExtractpeakrangeHzLabel       matlab.ui.control.Label
        GroupButtonGroup              matlab.ui.container.ButtonGroup
        Experimental2Button           matlab.ui.control.ToggleButton
        Experimental1Button           matlab.ui.control.ToggleButton
        Control2Button                matlab.ui.control.ToggleButton
        Control1Button                matlab.ui.control.ToggleButton
        SubjectnumberSpinner          matlab.ui.control.Spinner
        SubjectnumberSpinnerLabel     matlab.ui.control.Label
        EpochparametersPanel          matlab.ui.container.Panel
        SessionnumberEditField        matlab.ui.control.NumericEditField
        SessionnumberEditFieldLabel   matlab.ui.control.Label
        BufferlengthsecondsEditField  matlab.ui.control.NumericEditField
        BufferlengthsecondsEditFieldLabel  matlab.ui.control.Label
        EpochlengthsecondsEditField   matlab.ui.control.NumericEditField
        EpochlengthsecondsEditFieldLabel  matlab.ui.control.Label
        NumberofepochsDropDown        matlab.ui.control.DropDown
        NumberofepochsDropDownLabel   matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        ForceplateDropDown            matlab.ui.control.DropDown
        ForceplateDropDownLabel       matlab.ui.control.Label
        IndividualtremoreventanalysisCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        excelInput
        params
    end
    
    methods (Access = private)
        
        function [data,NS2] = getData(app)
            openNSx;
            app.params.sourceFile = [NS2.MetaTags.Filename NS2.MetaTags.FileExt];
            data{1} = double(NS2.Data');
            NS2 = rmfield(NS2,'Data');
            app.params.filename = [app.params.sourceFile(1:end-4) '.mat'];

            %%%%%%%% Re-assign channelID to legacy variables %%%%%%%%%%
            app.params.channelSig = find(NS2.MetaTags.ChannelID==app.params.chSig);
            if app.params.timecut ~= 0
                data{1} = data{1}(app.params.trimStartTime*app.params.sf+1:app.params.trimStopTime*app.params.sf,:);
            end
            if app.params.chRef == 0
                app.params.channelRef = 0;
                data{1,2} = data{1}(:,app.params.channelSig);
            else
                app.params.channelRef = find(NS2.MetaTags.ChannelID==app.params.chRef);
                data{1,2} = data{1}(:,app.params.channelSig) - data(:,app.params.channelRef1);
            end
        end

        function data = powerSpectralDensity(app,data)
            app.params.N = app.params.sf*app.params.timeframe; 
            app.params.freqStep = app.params.sf/app.params.N; 
            app.params.n1s = app.params.sf;          
            app.params.nCoh = app.params.n1s*app.params.cohSec; 
            app.params.freqCohRes = app.params.sf/app.params.nCoh;
            app.params.endNum = fix(length(data{1,1})/app.params.n1s) - app.params.timeframe-ceil(app.params.timeshift);
            app.params.shiftedFrame = app.params.n1s*app.params.timeshift;
            
            for s = 1:app.params.endNum

                y1 = data{1,2}((1+(s-1)*app.params.n1s):(app.params.N+(s-1)*app.params.n1s));  % Each iteration grabs 20s of data and the starting point is shifted 1 s to the right of the prior starting point

                [PxxY1, app.params.freqPsY1] = pwelch(y1,hanning(app.params.nCoh),1/2*app.params.nCoh,app.params.nCoh,app.params.sf); % PSD by Wilch method and Hanning window
                data{2,1}(:,s) = PxxY1;

                app.params.freqLabel(:,s) = app.params.freqPsY1;
                app.params.timeLabel(1:(app.params.nCoh/2+1),s) = s-1;

            end
            
            clear s i PxxY1 y1
        end
        
        function [tremor,move,epochIdxs,rawData,tremAmp,normTremAmp,iei] = isolatedTremorEvents(app,data)
            
            tremor = cell(1); move = cell(1);
            epochIdxs = cell(1); rawData = cell(app.params.numEpochs);
            for t = 1:app.params.numEpochs
                rawData{1,t} = data{1,1}(app.params.extractWndw(t,1)*app.params.sf:app.params.extractWndw(t,2)*app.params.sf);
                [tremor,move,epochIdxs,data] = extractDualFPtremor(rawData{1,t},data,app.params,tremor,move,epochIdxs,t);
                %normTremAmpAll{1}(1,t) =  
                for k = 1:length(tremor{t,5})
                    allTremAmp{t}(1,k) = mean(tremor{t,5}{k}(:));
                    allTremAmp{t}(2,k) = mean(tremor{t,2}{k}(:));
                    all40hzPkAvg{t}(1,k) = mean(tremor{t,12}{k}(:));
                    all40hzPkAvg{t}(2,k) = mean(tremor{t,13}{k}(:));
                    if k ~= length(tremor{t,5})
                        iei(k,t) = epochIdxs{t,1}(k+1,1) - epochIdxs{t,1}(k,2);
                    end

                    allNormTremAmp{t}(1,k) = allTremAmp{t}(1,k) / all40hzPkAvg{t}(1,k);
                    allNormTremAmp{t}(2,k) = allTremAmp{t}(2,k) / all40hzPkAvg{t}(2,k);
                end
                tremAmp = mean(allTremAmp{t},2,'omitnan');
                tremAmp = tremAmp';
                normTremAmp = mean(allNormTremAmp{t},2,'omitnan');
                normTremAmp = normTremAmp';
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function defaultValues(app)

            close all; clearvars -except app
            app.excelInput = readcell('fpParams.xlsx');
            app.params = struct();
       
            app.SubjectnumberSpinner.Value = app.excelInput{1,2};
            app.NumberofepochsDropDown.Value = mat2str(app.excelInput{3,2});
            app.EpochlengthsecondsEditField.Value = app.excelInput{4,2};
            app.BufferlengthsecondsEditField.Value = app.excelInput{5,2};
            app.ExtractpeakrangeHzDropDown.Value = mat2str([app.excelInput{6,2} app.excelInput{6,3}]);
            app.ForceplateDropDown.Value = mat2str(app.excelInput{7,2});
            app.IndividualtremoreventanalysisCheckBox.Value = app.excelInput{8,2};
            app.ExperimentDropDown.Value = app.excelInput{9,2};
            app.SessionnumberEditField.Value = app.excelInput{10,2};

            switch str2double(app.excelInput{2,2})
                case 1
                    app.GroupButtonGroup.SelectedObject = app.Control1Button;
                    app.params.group = 1;
                case 2
                    app.GroupButtonGroup.SelectedObject = app.Control2Button;
                    app.params.group = 2;
                case 3
                    app.GroupButtonGroup.SelectedObject = app.Experimental1Button;
                    app.params.group = 3;
                case 4
                    app.GroupButtonGroup.SelectedObject = app.Experimental2Button;
                    app.params.group = 4;
            end

            app.params.sxNum = app.excelInput{1,2};
            app.params.numEpochs = app.excelInput{3,2};
            app.params.epochLng = app.excelInput{4,2};
            app.params.bufferLng = app.excelInput{5,2};
            app.params.extractFreqRange = [app.excelInput{6,2} app.excelInput{6,3}];
            app.params.chSig = app.excelInput{7,2};
            app.params.indTremor = app.excelInput{8,2};
            app.params.workbookName = app.excelInput{9,2};
            app.params.sessionNum = app.excelInput{10,2};
            app.params.compile = 0;

            app.params.chRef = 0;

            app.params.BW = 0; 
            app.params.timecut = 0; 
            app.params.timeshift = 0; 
            app.params.trimStartTime = 0; 
            app.params.trimStopTime = 390; 
            
            app.params.sf = 1000; 
            app.params.timeframe = 20; 
            app.params.cohSec = 1;
            
            app.params.plotLimits = [0,50,0,200];

            % Define the frequency range for normalizing data
            app.params.sumWndw = [35,45];

        end

        % Selection changed function: GroupButtonGroup
        function groupSelection(app, event)
            switch app.GroupButtonGroup.SelectedObject 
                case app.Control1Button
                    app.params.group = 1;
                case app.Control2Button
                    app.params.group = 2;
                case app.Experimental1Button
                    app.params.group = 3;
                case app.Experimental2Button
                    app.params.group = 4;
            end
        end

        % Value changed function: NumberofepochsDropDown
        function numberOfEpochs(app, event)
            app.params.numEpochs = str2double(app.NumberofepochsDropDown.Value);
        end

        % Value changed function: EpochlengthsecondsEditField
        function lengthOfEpochs(app, event)
            app.params.epochLng = app.EpochlengthsecondsEditField.Value;
        end

        % Value changed function: IndividualtremoreventanalysisCheckBox
        function individualTremorEvents(app, event)
            if app.IndividualtremoreventanalysisCheckBox.Value == 1
                app.params.indTremor = 1;
            else
                app.params.indTremor = 0;
            end
        end

        % Value changed function: SubjectnumberSpinner
        function subjectNumber(app, event)
            app.params.sxNum = app.SubjectnumberSpinner.Value;
        end

        % Value changed function: BufferlengthsecondsEditField
        function lengthOfBuffer(app, event)
            app.params.bufferLng = app.BufferlengthsecondsEditField.Value;
        end

        % Value changed function: ExtractpeakrangeHzDropDown
        function extractPeakFreqRange(app, event)
            app.params.extractFreqRange = str2num(app.ExtractpeakrangeHzDropDown.Value);
        end

        % Button pushed function: RunButton
        function runAnalysis(app, event)

            close all; clearvars -except app
            if app.params.compile == 0
            
                for i = 1:app.params.numEpochs
                    if i == 1
                        app.params.extractWndw(i,1) = 1;
                        app.params.extractWndw(i,2) = app.params.epochLng;
                    elseif app.params.bufferLng ~= 0
                        app.params.extractWndw(i,1) = app.params.bufferLng + app.params.epochLng*(i-1) + 1;
                        app.params.extractWndw(i,2) = app.params.bufferLng + app.params.epochLng*i; 
                    else
                        app.params.extractWndw(i,1) = app.params.epochLng*(i-1) + 1;
                        app.params.extractWndw(i,2) = app.params.epochLng*i;
                    end
                end
                
                [data,NS2] = app.getData();
    
                app.params.title{1} = [sprintf('%d %s',app.params.chSig,app.params.sourceFile(1:end-4))];
                
                data = powerSpectralDensity(app,data);
    
                % Extract individual tremor event data
                %%%%%%%%%%%%%%%% NEED TO ADD GPU FOR CERTAIN FUNCTIONS
                if app.params.indTremor==1
                    [tremor,move,epochIdxs,rawData,tremAmp,normTremAmp,iei] = app.isolatedTremorEvents(data);
                    tremDur = mean(tremor{1,10}(:),'omitnan');
                    tremFreq = mean(tremor{9}(:),'omitnan');
                    numTremSegs = length(tremor{1});
                    tremOccur = numTremSegs / app.params.epochLng;

                    moveVec = horzcat(move{1}{:});
                    tremVec = horzcat(tremor{1}{:});

                    tremPsd = cell(1);
                    tremPsd{1} = tremVec;
                    tremPsd{2} = tremVec;
                    tremPsd = powerSpectralDensity(app,tremPsd);

                    epochLng = floor(length(tremVec)/1000);
                    for i = 1:app.params.numEpochs
                        extractWndw(i,1) = 1;
                        extractWndw(i,2) = epochLng;
                    end

                    extractWndw(:,3) = extractWndw(:,2) - app.params.timeframe;
                    for i = 1:app.params.numEpochs
                        mPsdInd(:,i) = mean(tremPsd{2,1}(:,extractWndw(i,1):extractWndw(i,3)),2);
                        nMeanPsdInd(:,i) = mPsdInd(:,i) / epochLng;
                        nMeanPsdInd2(:,i) = mPsdInd(:,i) / length(tremor{1});
        
                        [maxPsdInd(1,i),maxPsdFreqInd(1,i)] = max(mPsdInd(:,i));
                        nMaxPsdInd(1,i) = maxPsdInd(1,i) / epochLng;
                        nMaxPsdInd2(i,i) = maxPsdInd(1,i) / length(tremor{1});
                    end

                end
                % histogram(iei{1}(:,1),100)
    
                %% Plot raw data
    
                figure('Name','Raw data'); hold on
                plot(data{1,2}(:,1))
                ylabel('\muV')
                title([sprintf('%s %d','Force plate: ', app.params.chSig)])
                set(gca,'FontSize',16)
    
                %% Plot PSDs
    
                figure(2);
                    gca3 = pcolor(app.params.timeLabel,app.params.freqLabel,data{2,1});
                    set(gca3, 'LineStyle','none');
                    colorbar('location','eastoutside');
                    caxis([0 1000]);
                    title(['PSD: ',app.params.title]); 
                    xlabel('Time (seconds)'); 
                    ylabel('Frequency (Hz)'); 
                    axis([-inf,inf,0,50]);
                    set(gca,'FontSize',16)
                    psdFigName = [app.params.title{1} '_Psd.fig'];
                    saveas(2,[app.params.title{1} '_Psd.fig']);
            
                    clear gca3
    
                %% Compute mean PSD and normalize data
    
                % Frequency band cutoffs
                bands = [1 3; 4 7; 8 12; 13 30];
                
                % Define the Endtime by subtract the timeframe (for internal consistency of previous version)
                app.params.extractWndw(:,3) = app.params.extractWndw(:,2) - app.params.timeframe;
                mPSD = cell(1); nMeanPsd = cell(1);
                
                % Mean PSD and normalization
                for i = 1:app.params.numEpochs
                    mPSD{1}(:,i) = mean(data{2,1}(:,app.params.extractWndw(i,1):app.params.extractWndw(i,3)),2);
                    app.params.sumPSD(1,i) = sum(mPSD{1}(app.params.sumWndw(1):app.params.sumWndw(2),i));
                    nMeanPsd{1}(:,i) = mPSD{1}(:,i) / app.params.sumPSD(1,i);
    
                    [maxPsd(1,i),maxPsdFreq(1,i)] = max(mPSD{1}(app.params.extractFreqRange(1):app.params.extractFreqRange(2),i));
                    [nMaxPsd(1,i),nMaxPsdFreq(1,i)] = max(nMeanPsd{1}(app.params.extractFreqRange(1):app.params.extractFreqRange(2),i));
    
                    maxFreq(1,i) = (app.params.extractFreqRange(1) + maxPsdFreq(1,i) - 1) * app.params.freqCohRes;
                    nMaxFreq(1,i) = (app.params.extractFreqRange(1) + nMaxPsdFreq(1,i) - 1) * app.params.freqCohRes;
    
                    nPsdAucAll{1,i} = cumtrapz(nMeanPsd{1}(5:35,i));
                    for k = 2:length(nPsdAucAll{1,i})
                        nPsdAucTotal{1}(i,k) = nPsdAucAll{1,i}(k) - nPsdAucAll{1,i}(k-1);
                    end
                    nPsdAuc(1,i) = trapz(nMeanPsd{1}(app.params.extractFreqRange(1):app.params.extractFreqRange(2),i));
                    nPsdAucPct(1,i) = (nPsdAuc(1,i) / nPsdAucAll{1,i}(end)) * 100;
    
                    for m = 1:4
                        nPsdBands{1}(i,m) = sum(nMeanPsd{1}(bands(m,1):bands(m,2),i));
                        nPsdBandAuc{1}(i,m) = sum(nPsdAucTotal{1}(i,bands(m,1):bands(m,2)));
                        nPsdBandsAucPct{1}(i,m) = (nPsdBandAuc{1}(i,m) / nPsdAucAll{1,i}(end)) * 100;
                    end
    
                    pk40hz(1,i) = max(mPSD{1}(35:45,i));
                end
    
                %% Plot normalized data
    
                figure(3);
                switch app.params.numEpochs
                    case 1
                        app.params.legData = {[num2str(app.params.extractWndw(1,1)) '-' num2str(app.params.extractWndw(1,2))]};
                    case 2
                        app.params.legData = {[num2str(app.params.extractWndw(1,1)) '-' num2str(app.params.extractWndw(1,2))],[num2str(app.params.extractWndw(2,1)) '-' num2str(app.params.extractWndw(2,2))]};
                    case 3
                        app.params.legData = {[num2str(app.params.extractWndw(1,1)) '-' num2str(app.params.extractWndw(1,2))],[num2str(app.params.extractWndw(2,1)) '-' num2str(app.params.extractWndw(2,2))],...
                            [num2str(app.params.extractWndw(3,1)) '-' num2str(app.params.extractWndw(3,2))]};
                    case 4
                        app.params.legData = {[num2str(app.params.extractWndw(1,1)) '-' num2str(app.params.extractWndw(1,2))],[num2str(app.params.extractWndw(2,1)) '-' num2str(app.params.extractWndw(2,2))],...
                            [num2str(app.params.extractWndw(3,1)) '-' num2str(app.params.extractWndw(3,2))],[num2str(app.params.extractWndw(4,1)) '-' num2str(app.params.extractWndw(4,2))]};
                    case 5
                         app.params.legData = {[num2str(app.params.extractWndw(1,1)) '-' num2str(app.params.extractWndw(1,2))],[num2str(app.params.extractWndw(2,1)) '-' num2str(app.params.extractWndw(2,2))],...
                            [num2str(app.params.extractWndw(3,1)) '-' num2str(app.params.extractWndw(3,2))],[num2str(app.params.extractWndw(4,1)) '-' num2str(app.params.extractWndw(4,2))],...
                            [num2str(app.params.extractWndw(5,1)) '-' num2str(app.params.extractWndw(5,2))]};
                    case 6
                        app.params.legData = {[num2str(app.params.extractWndw(1,1)) '-' num2str(app.params.extractWndw(1,2))],[num2str(app.params.extractWndw(2,1)) '-' num2str(app.params.extractWndw(2,2))],...
                            [num2str(app.params.extractWndw(3,1)) '-' num2str(app.params.extractWndw(3,2))],[num2str(app.params.extractWndw(4,1)) '-' num2str(app.params.extractWndw(4,2))],...
                            [num2str(app.params.extractWndw(5,1)) '-' num2str(app.params.extractWndw(5,2))],[num2str(app.params.extractWndw(6,1)) '-' num2str(app.params.extractWndw(6,2))]};
                end
                    app.params.col = {'k','r','b','c','g','m'};
                    subplot(2,1,1); hold on
                    for i = 1:app.params.numEpochs
                        plot(app.params.freqPsY1,mPSD{1}(:,i),'Color',app.params.col{i},'LineWidth',1);
                    end
                    title(['mean PSD: ' app.params.title{1}]);
                    xlabel('Frequency (Hz)');
                    ylabel('Mean PSD (dB/Hz)');
                    axis([app.params.plotLimits(1:3),max(maxPsd(1,:))*1.2]);
                    legend(app.params.legData{1:app.params.numEpochs})
                    set(gca,'FontSize',16)
                    
                    subplot(2,1,2); hold on
                    for i = 1:app.params.numEpochs
                        plot(app.params.freqPsY1,nMeanPsd{1}(:,i),'Color',app.params.col{i},'LineWidth',1);
                    end
                    title(['Normalized PSD: ' app.params.title{1}]);
                    xlabel('Frequency (Hz)');
                    ylabel('Norm PSD (dB/Hz)');
                    xlim([0 50])
                    %axis([app.params.plotLimits(1:3),1*1.2]);
                    legend(app.params.legData{1:app.params.numEpochs})
                    set(gca,'FontSize',16)
                    
                    saveas(3,[app.params.title{1} '_normPSD.fig']);
    
                clear i t k
    
                write2excel = cell(10,1);
                write2excel = {app.params.sxNum; num2str(app.params.group); app.params.numEpochs;...
                    app.params.epochLng; app.params.bufferLng; app.params.extractFreqRange;...
                    app.params.chSig; app.params.indTremor; app.params.workbookName; app.params.sessionNum};
                cd('/Users/asf2200/Library/CloudStorage/Dropbox-Alex/Alex Fanning/Kuo lab/Basic Science/Code/behaviorAnalysis/forcePlate/')
                writecell(write2excel,'fpParams.xlsx','Range','B1')
    
                app.params.xlCellId = {['B' num2str(app.params.sxNum+1)],['H' num2str(app.params.sxNum+1)],['N' num2str(app.params.sxNum+1)],['T' num2str(app.params.sxNum+1)];...
                    ['B' num2str(app.params.sxNum+24)],['H' num2str(app.params.sxNum+24)],['N' num2str(app.params.sxNum+24)],['T' num2str(app.params.sxNum+24)];...
                    ['B' num2str(app.params.sxNum+49)],['C' num2str(app.params.sxNum+49)],['D' num2str(app.params.sxNum+49)],['E' num2str(app.params.sxNum+49)];...
                    ['H' num2str(app.params.sxNum+49)],['I' num2str(app.params.sxNum+49)] ['J' num2str(app.params.sxNum+49)],['K' num2str(app.params.sxNum+49)];...
                    ['N' num2str(app.params.sxNum+49)],['O' num2str(app.params.sxNum+49)] ['P' num2str(app.params.sxNum+49)],['Q' num2str(app.params.sxNum+49)];
                    ['B' num2str(app.params.sxNum+1)],['B' num2str(app.params.sxNum+24)] ['B' num2str(app.params.sxNum+47)],['B' num2str(app.params.sxNum+70)];
                    ['A' num2str(1)],['A' num2str(24)] ['A' num2str(47)],['A' num2str(70)]};
                app.params.xlCellId2 = {['A' num2str(1)];['A' num2str(2)];['A' num2str(25)];['A' num2str(49)];['G' num2str(49)];['G' num2str(1)];['M' num2str(1)];['S' num2str(1)];['B' num2str(49)];['C' num2str(49)];['D' num2str(49)];['E' num2str(49)];['H' num2str(49)];['I' num2str(49)];['J' num2str(49)];['K' num2str(49)];['N' num2str(49)];['O' num2str(49)];['P' num2str(49)];['Q' num2str(49)];['M' num2str(49)]};
                app.params.xlCellId3 = {['B' num2str(1)];['C' num2str(1)];['D' num2str(1)];['E' num2str(1)];['H' num2str(1)];['I' num2str(1)];['J' num2str(1)];['K' num2str(1)];['N' num2str(1)];['O' num2str(1)];['P' num2str(1)];['Q' num2str(1)];['T' num2str(1)];['U' num2str(1)];['V' num2str(1)];['W' num2str(1)]};
                app.params.xlCellId4 = {['B' num2str(app.params.sxNum+1)],['F' num2str(app.params.sxNum+1)],['J' num2str(app.params.sxNum+1)],['N' num2str(app.params.sxNum+1)];...
                    ['B' num2str(1)],['F' num2str(1)],['J' num2str(1)],['N' num2str(1)]};
                app.params.dataNames = {'Ctrl group 1';'nPsdBands';'nPsdBandsAucPct';'nMaxPsd';'nMaxPsdFreq';'Ctrl group 2'; 'Expt group 1'; 'Expt group 2';'Ctrl group 1';'Ctrl group 2';'Expt group 1';'Expt group 2';'Ctrl group 1';'Ctrl group 2';'Expt group 1';'Expt group 2';'Ctrl group 1';'Ctrl group 2';'Expt group 1';'Expt group 2';'nPsdAucPct'};
                app.params.dataNames2 = {'1-3 Hz';'4-7 Hz';'8-12 Hz';'13-30 Hz';'1-3 Hz';'4-7 Hz';'8-12 Hz';'13-30 Hz';'1-3 Hz';'4-7 Hz';'8-12 Hz';'13-30 Hz';'1-3 Hz';'4-7 Hz';'8-12 Hz';'13-30 Hz'};
                app.params.dataNames3 = {'Ctrl group 1'; 'Ctrl group 2'; 'Expt group 1'; 'Expt group 2'};
                app.params.dataNames4 = {'Hets'; 'Ctrl group 2'; 'Homs'; 'Expt group 2'};
                app.params.dataNames5 = {'tremAmp1','tremAmp2', 'normTremAmp1','normTremAmp2','tremDur', 'tremFreq', 'tremOccur', 'maxPsdInd', 'nMaxPsdInd', 'nMaxPsdInd2', 'maxPsdFreqInd'};
  
                if strcmpi(app.params.workbookName,'grid2kd') || strcmpi(app.params.workbookName,'eaat2')|| strcmpi(app.params.workbookName,'17Jpharma')
                    app.params.xlTabNames = {'Key metrics wk 0';'Key metrics wk 1';'Key metrics wk 2';'Key metrics wk 3';'Key metrics wk 4';'Key metrics wk 5';...
                        'Key metrics wk 6';'Key metrics wk 7';'Key metrics wk 8';'Key metrics wk 9';'Key metrics wk 10';'Key metrics wk 11';'Key metrics wk 12';'Key metrics wk 13';'Key metrics wk 14';'Key metrics wk 15';...
                        'Key metrics wk 16';'Key metrics wk 17';'Key metrics wk 18';'Key metrics wk 19';'Key metrics wk 20';'Key metrics wk 21';'Key metrics wk 22';'Key metrics wk 23';'Key metrics wk 24'};
                    app.params.xlTabNames2 = {'normPsd wk 0';'normPsd wk 1';'normPsd wk 2';'normPsd wk 3';'normPsd wk 4';'normPsd wk 5';'normPsd wk 6';...
                        'normPsd wk 7';'normPsd wk 8';'normPsd wk 9';'normPsd wk 10';'normPsd wk 11';'normPsd wk 12';'normPsd wk 13';'normPsd wk 14';...
                        'normPsd wk 15';'normPsd wk 16';'normPsd wk 17';'normPsd wk 18';'normPsd wk 19';'normPsd wk 20';'normPsd wk 21';'normPsd wk 22';'normPsd wk 23';'normPsd wk 24'};
                elseif strcmpi(app.params.workbookName,'ryr')
                    app.params.xlTabNames = {'Key metrics epoch 1'; 'Key metrics epoch 2';'Key metrics epoch 3'; 'Key metrics epoch 4';'Key metrics epoch 5'; 'Key metrics epoch 6'};
                    app.params.xlTabNames2 = {'NormPsd epoch 1'; 'NormPsd epoch 2';'NormPsd epoch 3'; 'NormPsd epoch 4';'NormPsd epoch 5'; 'NormPsd epoch 6'};
                elseif strcmpi(app.params.workbookName,'ucbPharma')
                    app.params.xlTabNames = {'Key metrics habituation';'Key metrics pre'; 'Key metrics post'};
                    app.params.xlTabNames2 = {'NormPsd habituation';'NormPsd pre'; 'NormPsd post'};
                    app.params.xlTabNames3 = {'Ind tremor habituation';'Ind tremor pre'; 'Ind tremor post'};
                elseif strcmpi(app.params.workbookName,'neurocrine')
                    app.params.xlTabNames = {'Key metrics pre'; 'Key metrics post'};
                    app.params.xlTabNames2 = {'NormPsd pre'; 'NormPsd post'};
                    app.params.xlTabNames3 = {'Ind tremor pre'; 'Ind tremor post'};
                else
                    app.params.xlTabNames = {'Key metrics'};
                    app.params.xlTabNames2 = {''};
                end
    
                for m = 1:app.params.numEpochs
                    if strcmpi(app.params.workbookName,'grid2kd') || strcmpi(app.params.workbookName,'eaat2') || strcmpi(app.params.workbookName,'17Jpharma') 
                        writematrix(nPsdBands{1}(m,:),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{1,app.params.group})
                        writematrix(nPsdBandsAucPct{1}(m,:),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{2,app.params.group})
                        writematrix(nMaxPsd(m),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{3,app.params.group})
                        writematrix(nMaxFreq(m),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{4,app.params.group})
                        writematrix(nPsdAucPct(m),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{5,app.params.group})
                            for d = 1:length(app.params.dataNames)
                                writecell(cellstr(app.params.dataNames{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId2{d})
                            end
                            for d = 1:length(app.params.dataNames2)
                                writecell(cellstr(app.params.dataNames2{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId3{d})
                            end
                            for d = 1:length(app.params.dataNames3)
                                writecell(cellstr(app.params.dataNames3{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames2{app.params.sessionNum},'Range',app.params.xlCellId{7,d})
                            end
            
                            writematrix(nMeanPsd{1}(:,m)',[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames2{app.params.sessionNum},'Range',app.params.xlCellId{6,app.params.group})
                    elseif strcmpi(app.params.workbookName,'ryr')
                        writematrix(nPsdBands{1}(m,:),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{m},'Range',app.params.xlCellId{1,app.params.group})
                        writematrix(nPsdBandsAucPct{1}(m,:),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{m},'Range',app.params.xlCellId{2,app.params.group})
                        writematrix(nMaxPsd(m),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{m},'Range',app.params.xlCellId{3,app.params.group})
                        writematrix(nMaxFreq(m),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{m},'Range',app.params.xlCellId{4,app.params.group})
                        writematrix(nPsdAucPct(m),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{m},'Range',app.params.xlCellId{5,app.params.group})
                        for d = 1:length(app.params.dataNames)
                            writecell(cellstr(app.params.dataNames{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{m},'Range',app.params.xlCellId2{d})
                        end
                        for d = 1:length(app.params.dataNames2)
                            writecell(cellstr(app.params.dataNames2{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{m},'Range',app.params.xlCellId3{d})
                        end
                        for d = 1:length(app.params.dataNames3)
                            writecell(cellstr(app.params.dataNames3{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames2{m},'Range',app.params.xlCellId{7,d})
                        end
                        writematrix(nMeanPsd{1}(:,m)',[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames2{m},'Range',app.params.xlCellId{6,app.params.group})
                    elseif strcmpi(app.params.workbookName,'neurocrine')
                        indTrem = [tremAmp,normTremAmp, tremDur, tremFreq, tremOccur, maxPsdInd, nMaxPsdInd, nMaxPsdInd2, maxPsdFreqInd];
                        writematrix(nPsdBands{1}(m,:),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{1,app.params.group})
                        writematrix(nPsdBandsAucPct{1}(m,:),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{2,app.params.group})
                        writematrix(nMaxPsd(m),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{3,app.params.group})
                        writematrix(nMaxFreq(m),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{4,app.params.group})
                        writematrix(nPsdAucPct(m),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{5,app.params.group})
                        for d = 1:length(app.params.dataNames)
                            writecell(cellstr(app.params.dataNames{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId2{d})
                        end
                        for d = 1:length(app.params.dataNames2)
                            writecell(cellstr(app.params.dataNames2{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId3{d})
                        end
                        for d = 1:length(app.params.dataNames3)
                            writecell(cellstr(app.params.dataNames3{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames2{app.params.sessionNum},'Range',app.params.xlCellId{7,d})
                            writecell(cellstr(app.params.dataNames3{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames3{app.params.sessionNum},'Range',app.params.xlCellId{7,d})
                        end
                        writematrix(nMeanPsd{1}(:,m)',[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames2{app.params.sessionNum},'Range',app.params.xlCellId{6,app.params.group})
                        writematrix(indTrem,[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames3{app.params.sessionNum},'Range',app.params.xlCellId{6,app.params.group})
                        writecell(cellstr(app.params.dataNames5),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames3{app.params.sessionNum},'Range','B1')
                    elseif strcmpi(app.params.workbookName,'ucbPharma')
                        indTrem = [tremAmp, normTremAmp,tremDur, tremFreq, tremOccur, maxPsdInd, nMaxPsdInd, nMaxPsdInd2, maxPsdFreqInd];
                        writematrix(nPsdBands{1}(m,:),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{1,app.params.group})
                        writematrix(nPsdBandsAucPct{1}(m,:),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{2,app.params.group})
                        writematrix(nMaxPsd(m),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{3,app.params.group})
                        writematrix(nMaxFreq(m),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{4,app.params.group})
                        writematrix(nPsdAucPct(m),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId{5,app.params.group})
                        for d = 1:length(app.params.dataNames)
                            writecell(cellstr(app.params.dataNames{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId2{d})
                        end
                        for d = 1:length(app.params.dataNames2)
                            writecell(cellstr(app.params.dataNames2{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames{app.params.sessionNum},'Range',app.params.xlCellId3{d})
                        end
                        for d = 1:length(app.params.dataNames3)
                            writecell(cellstr(app.params.dataNames3{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames2{app.params.sessionNum},'Range',app.params.xlCellId{7,d})
                            writecell(cellstr(app.params.dataNames3{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames3{app.params.sessionNum},'Range',app.params.xlCellId{7,d})
                        end
                        writecell(cellstr(app.params.dataNames5),[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames3{app.params.sessionNum},'Range','B1')

                        writematrix(nMeanPsd{1}(:,m)',[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames2{app.params.sessionNum},'Range',app.params.xlCellId{6,app.params.group})
                        if app.params.sessionNum==1
                            writematrix(nMaxPsd,[app.params.workbookName '_summaryStats.xlsx'],'Sheet','psdGroupAssign','Range',app.params.xlCellId4{1,app.params.group})
                            for d = 1:length(app.params.dataNames3)
                                writecell(cellstr(app.params.dataNames4{d}),[app.params.workbookName '_summaryStats.xlsx'],'Sheet','psdGroupAssign','Range',app.params.xlCellId4{2,d})
                            end
                        end
                        writematrix(nMeanPsd{1}(:,m)',[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames2{app.params.sessionNum},'Range',app.params.xlCellId{6,app.params.group})
                        writematrix(indTrem,[app.params.workbookName '_summaryStats.xlsx'],'Sheet',app.params.xlTabNames3{app.params.sessionNum},'Range',app.params.xlCellId{6,app.params.group})
                    end
                end
                
                cd(app.params.filepath)
                myData = struct('var1', app, 'var2', data,'var3', maxFreq, 'var4', maxPsd,'var5', maxPsdFreq, 'var6', mPSD,'var7', nMaxFreq, 'var8', nMaxPsd,'var9', nMaxPsdFreq, 'var10',nMeanPsd,...
                    'var11', nPsdAuc, 'var13', nPsdAucPct, 'var14', nPsdAucTotal,'var15', nPsdBandAuc,'var16', nPsdBands,'var17', nPsdBandsAucPct, 'var18', NS2,'var19', pk40hz);
                myData = myData(1);
                save([app.params.filename(1,1:end-4) '_' mat2str(app.params.chSig) '.mat'],'-struct','myData')
            else
                
                cd('/Users/asf2200/Library/CloudStorage/Dropbox-Alex/Alex Fanning/Kuo lab/Basic Science/Code/behaviorAnalysis/forcePlate/')
                clear; close all
                filename = uigetfile('.xlsx');
                worksheetNames = sheetnames(filename);
                for i = 1:length(worksheetNames)
                    tempOrder(i) = regexp(worksheetNames{i},'\d*','Match');
                    wkshtOrder(i) = str2double(tempOrder{i});
                end
                clear tempOrder
%% 
                nMaxPsd = cell(1,4);
                nMaxPsdFreq = cell(1,4);
                nPsdAucPct = cell(1,4);
                nMaxPsdBands = cell(4,4);
                nPsdAucPctBands = cell(4,4);
                count = 1;
                numTimepts = length(wkshtOrder)/2;
                col = [2,8,14,20; 2,3,4,5; 8,9,10,11; 14,15,16,17];
                col2 = [2,3,4,5; 8,9,10,11; 14,15,16,17; 20,21,22,23];
                col3 = [1,24,47,80];
                for i = wkshtOrder(1):wkshtOrder(end)
                    for t = 1:2
                        if t == 1
                            k = find(i==wkshtOrder,1,'first');
                        
                            tabData = readtable(filename ,'Sheet',k);
                            for m = 1:4
                                if any(isnumeric(tabData{1:19,col(1,m)}))
                                    
                                    nanIdxs = find(isnan(tabData{1:19,col(1,m)}));
                                    numSxs(m) = nanIdxs(1)-1;
    
                                    row = length(tabData{49:49+numSxs(m)-1,col(2,m)});
                                    nMaxPsd{m}(1:row,count) = tabData{49:49+numSxs(m)-1,col(2,m)};
                                    nMaxPsdFreq{m}(1:row,count) = tabData{49:49+numSxs(m)-1,col(3,m)};
                                    nPsdAucPct{m}(1:row,count) = tabData{49:49+numSxs(m)-1,col(4,m)};

                                    for j = 1:4
                                        nMaxPsdBands{j,m}(1:row,count) = tabData{1:numSxs(m),col2(m,j)};
                                        nPsdAucPctBands{j,m}(1:row,count) = tabData{24:24+numSxs(m)-1,col2(m,j)};
                                    end
                                else
                                    numSxs(m) = NaN;
                                    continue
                                end
                                if numSxs(m) == 0;
                                    numSxs(m) = NaN;
                                end
                            end
                        else
                            k = find(i==wkshtOrder,1,'last');

                            tabData = readtable(filename,'Sheet',k);
                            if isnan(numSxs(1))
                                col3 = [NaN,NaN,24,47];
                            end
                            for m = 1:4
                                if ~isnan(numSxs(m))
                                    row2 = length(tabData{col3(m):col3(m)+numSxs(m)-1,2:end});
                                    for p = 1:numSxs(m)
                                        nPsdIndTraces{p,m}(i+1,1:row2) = tabData{col3(m)+p-1,2:end};
                                    end
                                    nPsdPopTraces{i+1,m}(1:numSxs(m),1:row2) = tabData{col3(m):col3(m)+numSxs(m)-1,2:end};
                                end
                            end
                        end
                    end
                    count = count + 1;
                end

                for m = 1:4
                        for i = 1:size(nMaxPsd{m},2)
                            for k = 1:size(nMaxPsd{m},1)
                                if isnan(numSxs(m))
                                    continue
                                else
                                    if nMaxPsd{m}(k,i)==0
                                        nMaxPsd{m}(k,i) = NaN;
                                        nMaxPsdFreq{m}(k,i) = NaN;
                                        nPsdAucPct{m}(k,i) = NaN;
                                    end
                                end
                            end
                            
                            if isnan(numSxs(m))
                                    continue
                            else
                                % population averages
                                nMaxPsdAvgs(m,i) = mean(nMaxPsd{m}(:,i),1,'omitnan');
                                nMaxPsdSe(m,i) = std(nMaxPsd{m}(:,i),0,1,'omitnan')/sqrt(size(nMaxPsd{m},1));
        
                                nPsdAucPctAvgs(m,i) = mean(nPsdAucPct{m}(:,i),1,'omitnan');
                                nPsdAucPctSe(m,i) = std(nPsdAucPct{m}(:,i),0,1,'omitnan')/sqrt(size(nPsdAucPct{m},1));
                                if ~isempty(nMaxPsdFreq{m})
                                    nMaxPsdFreqAvgs(m,i) = mean(nMaxPsdFreq{m}(:,i),1,'omitnan');
                                    nMaxPsdFreqSe(m,i) = std(nMaxPsdFreq{m}(:,i),0,1,'omitnan')/sqrt(size(nMaxPsdFreq{m},1));
                                else
                                    nMaxPsdFreqAvgs(m,i) = NaN;
                                    nMaxPsdFreqSe(m,i) = NaN;
                                end
                            end
                        end
                end


                % Plot data
                %groupIdxs = find(~isnan(nMaxPsdAvgs(:,1)))';
                groupIdxs = find(~isnan(numSxs))';
                filColor = copper(4);
                if strcmpi(filename,'eaat2')
                    legNames = {'17J (Sham)','Control 2','17J (EAAT2)','Experimental 2'};
                else
                    legNames = {'Control 1','Control 2','Experimental 1','Experimental 2'};
                end

                figure(1); hold on
                for ii = 1:length(groupIdxs)

                    bound1 = nMaxPsdAvgs(groupIdxs(ii),:) + nMaxPsdSe(groupIdxs(ii),:);
                    bound2 = nMaxPsdAvgs(groupIdxs(ii),:) - nMaxPsdSe(groupIdxs(ii),:);
                    x = 1:numTimepts;
                    shade(x,bound1,x,bound2,'FillType',[1 2],'FillColor',filColor(groupIdxs(ii),:),'FillAlpha',0.1)
                    plot(nMaxPsdAvgs(groupIdxs(ii),:),'LineWidth',2,'Color',filColor(groupIdxs(ii),:))

%                     if length(groupIdxs)==1
%                         legend('','','',legNames{groupIdxs(1)},'Location', 'best')
%                     elseif length(groupIdxs)==2
%                         legend('','','',legNames{groupIdxs(1)},'','','',legNames{groupIdxs(2)},'Location', 'best')
%                     elseif length(groupIdxs)==3
%                         legend('','','',legNames{groupIdxs(1)},'','','',legNames{groupIdxs(2)},'','','',legNames{groupIdxs(3)},'Location', 'best')
%                     else
%                         legend('','','',legNames{groupIdxs(1)},'','','',legNames{groupIdxs(2)},'','','',legNames{groupIdxs(3)},'','','',legNames{groupIdxs(4)},'Location', 'best')
%                     end
                    set(gca,'FontSize',18)
                end
                ax = gca;
                ax.Box = 'off';
                ax.LineWidth = 1.5;
                ax.FontSize = 10;
                ax.FontName = 'Times New Roman';
                ax.TickDir = 'out';
                ax.TickLength = [0.02, 0.02];
                ax.XAxis.MinorTick = 'off';
                ax.YAxis.MinorTick = 'off';
                xlim([0 numTimepts+1])

                if strcmpi(filename,'eaat2')
                    xticks(1:length(wkshtOrder)/2)
                    xticklabels({'Pre',2:2:length(wkshtOrder)})
                    xlabel('Week re: injection')
                end
                ylabel('Norm. Peak PSD')
                set(gca,'FontSize',20)
                saveas(figure(1),[filename '_peakPSD.tif'])

                figure(2); hold on
                for ii = 1:length(groupIdxs)
                    bound1 = nMaxPsdFreqAvgs(groupIdxs(ii),:) + nMaxPsdFreqSe(groupIdxs(ii),:);
                    bound2 = nMaxPsdFreqAvgs(groupIdxs(ii),:) - nMaxPsdFreqSe(groupIdxs(ii),:);
                    plot(nMaxPsdFreqAvgs(groupIdxs(ii),:),'LineWidth',2,'Color',filColor(groupIdxs(ii),:))
                    shade(x,bound1,x,bound2,'FillType',[1 2],'FillColor',filColor(groupIdxs(ii),:),'FillAlpha',0.1)
                    ax = gca;
                    ax.Box = 'off';
                    ax.LineWidth = 1.5;
                    ax.FontSize = 10;
                    ax.FontName = 'Times New Roman';
                    ax.TickDir = 'out';
                    ax.TickLength = [0.02, 0.02];
                    ax.XAxis.MinorTick = 'off';
                    ax.YAxis.MinorTick = 'off';
                    
                    xlim([0 numTimepts+1])
                    xlabel('Session number')
                    ylabel('Norm. PSD peak freq.')

                    if strcmpi(filename,'eaat2')
                        xticks(1:length(wkshtOrder)/2)
                        xticklabels({'Pre',2:2:length(wkshtOrder)})
                        xlabel('Week re: injection')
                    end

%                     if length(groupIdxs)==1
%                         legend('','','',legNames{groupIdxs(1)},'Location', 'best')
%                     elseif length(groupIdxs)==2
%                         legend(legNames{groupIdxs(1)},'','','','','','','','','','','',legNames{groupIdxs(2)},'Location', 'best')
%                     elseif length(groupIdxs)==3
%                         legend('','','','','','',legNames{groupIdxs(1)},'','','','','','',legNames{groupIdxs(2)},'','','',legNames{groupIdxs(3)},'Location', 'best')
%                     else
%                         legend('','','',legNames{groupIdxs(1)},'','','',legNames{groupIdxs(2)},'','','',legNames{groupIdxs(3)},'','','',legNames{groupIdxs(4)},'Location', 'best')
%                     end
                    set(gca,'FontSize',18)
                end
                saveas(figure(2),[filename '_peakPSDfreq.tif'])

                figure(3); hold on
                for ii = 1:length(groupIdxs)
                    bound1 = nPsdAucPctAvgs(groupIdxs(ii),:) + nPsdAucPctSe(groupIdxs(ii),:);
                    bound2 = nPsdAucPctAvgs(groupIdxs(ii),:) - nPsdAucPctSe(groupIdxs(ii),:);
                    plot(nPsdAucPctAvgs(groupIdxs(ii),:),'LineWidth',2,'Color',filColor(groupIdxs(ii),:))
                    shade(x,bound1,x,bound2,'FillType',[1 2],'FillColor',filColor(groupIdxs(ii),:),'FillAlpha',0.1)
                    ax = gca;
                    ax.Box = 'off';
                    ax.LineWidth = 1.5;
                    ax.FontSize = 10;
                    ax.FontName = 'Times New Roman';
                    ax.TickDir = 'out';
                    ax.TickLength = [0.02, 0.02];
                    ax.XAxis.MinorTick = 'off';
                    ax.YAxis.MinorTick = 'off';
                    xlim([0 numTimepts+1])
                    xlabel('Session number')
                    ylabel('AUC (% of total)')

                    if strcmpi(filename,'eaat2')
                        xticks(1:length(wkshtOrder)/2)
                        xticklabels({'Pre',2:2:length(wkshtOrder)})
                        xlabel('Week re: injection')
                    end
%                     if length(groupIdxs)==1
%                         legend('','','',legNames{groupIdxs(1)},'Location', 'best')
%                     elseif length(groupIdxs)==2
%                         legend(legNames{groupIdxs(1)},'','','',legNames{groupIdxs(2)},'Location', 'best')
%                     elseif length(groupIdxs)==3
%                         legend('','','','','','',legNames{groupIdxs(1)},'','','','','','',legNames{groupIdxs(2)},'','','',legNames{groupIdxs(3)},'Location', 'best')
%                     else
%                         legend('','','',legNames{groupIdxs(1)},'','','',legNames{groupIdxs(2)},'','','',legNames{groupIdxs(3)},'','','',legNames{groupIdxs(4)},'Location', 'best')
%                     end
                    set(gca,'FontSize',18)
                end
                saveas(figure(3),[filename '_nPsdAucPct.tif'])

            end

        end

        % Button pushed function: SetdirectoryButton
        function setDirectory(app, event)
            app.params.filepath = uigetdir;
            cd(app.params.filepath)
        
            % Display the selected file path in the text field
            app.PathEditField.Value = app.params.filepath;
            figure(app.UIFigure);
        end

        % Value changed function: ExperimentDropDown
        function experiment(app, event)
            app.params.workbookName = app.ExperimentDropDown.Value;
        end

        % Value changed function: SessionnumberEditField
        function sessionNumber(app, event)
            app.params.sessionNum = app.SessionnumberEditField.Value;
        end

        % Value changed function: ForceplateDropDown
        function forcePlateNum(app, event)
            app.params.chSig = str2double(app.ForceplateDropDown.Value);
        end

        % Value changed function: CompiletimepointsCheckBox
        function compileTimepoints(app, event)
            if app.CompiletimepointsCheckBox.Value == 1
                app.params.compile = 1;
            else
                app.params.compile = 0;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.IndividualtremoreventanalysisCheckBox = uicheckbox(app.UIFigure);
            app.IndividualtremoreventanalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @individualTremorEvents, true);
            app.IndividualtremoreventanalysisCheckBox.Text = 'Individual tremor event analysis';
            app.ForceplateDropDownLabel = uilabel(app.UIFigure);
            app.ForceplateDropDownLabel.Text = 'Force plate';
            app.ForceplateDropDown = uidropdown(app.UIFigure);
            app.ForceplateDropDown.Items = {'141', '143', '144'};
            app.ForceplateDropDown.ValueChangedFcn = createCallbackFcn(app, @forcePlateNum, true);
            app.ForceplateDropDown.Value = '141';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runAnalysis, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, 'playButton.png');
            app.RunButton.Text = 'Run';
            app.EpochparametersPanel = uipanel(app.UIFigure);
            app.EpochparametersPanel.Title = 'Epoch parameters';
            app.NumberofepochsDropDownLabel = uilabel(app.EpochparametersPanel);
            app.NumberofepochsDropDownLabel.Text = 'Number of epochs';
            app.NumberofepochsDropDown = uidropdown(app.EpochparametersPanel);
            app.NumberofepochsDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.NumberofepochsDropDown.ValueChangedFcn = createCallbackFcn(app, @numberOfEpochs, true);
            app.NumberofepochsDropDown.Value = '1';
            app.EpochlengthsecondsEditFieldLabel = uilabel(app.EpochparametersPanel);
            app.EpochlengthsecondsEditFieldLabel.Text = 'Epoch length (seconds)';
            app.EpochlengthsecondsEditField = uieditfield(app.EpochparametersPanel, 'numeric');
            app.EpochlengthsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @lengthOfEpochs, true);
            app.EpochlengthsecondsEditField.Value = 300;
            app.BufferlengthsecondsEditFieldLabel = uilabel(app.EpochparametersPanel);
            app.BufferlengthsecondsEditFieldLabel.Text = 'Buffer length (seconds)';
            app.BufferlengthsecondsEditField = uieditfield(app.EpochparametersPanel, 'numeric');
            app.BufferlengthsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @lengthOfBuffer, true);
            app.SessionnumberEditFieldLabel = uilabel(app.EpochparametersPanel);
            app.SessionnumberEditFieldLabel.Text = 'Session number';
            app.SessionnumberEditField = uieditfield(app.EpochparametersPanel, 'numeric');
            app.SessionnumberEditField.ValueChangedFcn = createCallbackFcn(app, @sessionNumber, true);
            app.SessionnumberEditField.Value = 1;
            app.SubjectnumberSpinnerLabel = uilabel(app.UIFigure);
            app.SubjectnumberSpinnerLabel.Text = 'Subject number';
            app.SubjectnumberSpinner = uispinner(app.UIFigure);
            app.SubjectnumberSpinner.ValueChangedFcn = createCallbackFcn(app, @subjectNumber, true);
            app.SubjectnumberSpinner.Value = 1;
            app.GroupButtonGroup = uibuttongroup(app.UIFigure);
            app.GroupButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @groupSelection, true);
            app.GroupButtonGroup.Title = 'Group';
            app.Control1Button = uitogglebutton(app.GroupButtonGroup);
            app.Control1Button.Text = 'Control 1';
            app.Control1Button.Value = true;
            app.Control2Button = uitogglebutton(app.GroupButtonGroup);
            app.Control2Button.Text = 'Control 2';
            app.Experimental1Button = uitogglebutton(app.GroupButtonGroup);
            app.Experimental1Button.Text = 'Experimental 1';
            app.Experimental2Button = uitogglebutton(app.GroupButtonGroup);
            app.Experimental2Button.Text = 'Experimental 2';
            app.ExtractpeakrangeHzLabel = uilabel(app.UIFigure);
            app.ExtractpeakrangeHzLabel.Text = 'Extract peak range (Hz)';
            app.ExtractpeakrangeHzDropDown = uidropdown(app.UIFigure);
            app.ExtractpeakrangeHzDropDown.Items = {'[15 25]', '[5 15]'};
            app.ExtractpeakrangeHzDropDown.ValueChangedFcn = createCallbackFcn(app, @extractPeakFreqRange, true);
            app.ExtractpeakrangeHzDropDown.Value = '[15 25]';
            app.SetdirectoryButton = uibutton(app.UIFigure, 'push');
            app.SetdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @setDirectory, true);
            app.SetdirectoryButton.Text = 'Set directory';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentDropDownLabel = uilabel(app.UIFigure);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.UIFigure);
            app.ExperimentDropDown.Items = {'grid2kd', 'ucbPharma', 'ryr', 'eaat2', '17Jpharma', 'neurocrine'};
            app.ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @experiment, true);
            app.ExperimentDropDown.Value = 'grid2kd';
            app.CompiletimepointsCheckBox = uicheckbox(app.UIFigure);
            app.CompiletimepointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @compileTimepoints, true);
            app.CompiletimepointsCheckBox.Text = 'Compile timepoints';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = forcePlateAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @defaultValues)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end